classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        OpenAudioFileButton             matlab.ui.control.Button
        AddNoiseButton                  matlab.ui.control.Button
        MAButton                        matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        MASettingPanel                  matlab.ui.container.Panel
        M2EditFieldLabel                matlab.ui.control.Label
        M2EditField                     matlab.ui.control.NumericEditField
        M1EditFieldLabel                matlab.ui.control.Label
        M1EditField                     matlab.ui.control.NumericEditField
        PlotPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RefreshButton                   matlab.ui.control.Button
        FinishButton                    matlab.ui.control.Button
        PlotSettingButtonGroup          matlab.ui.container.ButtonGroup
        TimeDomainButton                matlab.ui.control.RadioButton
        FrequencyDomainmagnitudeButton  matlab.ui.control.RadioButton
        FrequencyDomainphaseButton      matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        path;
        filename ;
        audioSignal;
        Fs;
        player;
        plot_len ;
        changed_signal;
        
    end
    
    methods (Access = private)
        
        function results = process(app)
            title(app.UIAxes , 'Time Domain');
            hold( app.UIAxes,'off');
            grid (app.UIAxes , 'on');
            
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            process(app)
        end

        % Button pushed function: OpenAudioFileButton
        function OpenAudioFileButtonPushed(app, event)
            [app.filename,app.path] = uigetfile('*.wav');
            [Signal , app.Fs] = audioread(fullfile(app.path , app.filename));
            app.audioSignal = Signal(1:length(Signal));
            app.changed_signal = app.audioSignal;
            app.plot_len = floor(length(app.changed_signal)/500);
            title(app.UIAxes , 'Time Domain');
            plot(app.UIAxes , app.changed_signal);
            app.M2EditField.Limits = [1 length(app.changed_signal)];
            app.M1EditField.Limits = [1 length(app.changed_signal)];
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.changed_signal = app.audioSignal;
            app.plot_len = floor(length(app.changed_signal)/500);
            app.M2EditField.Limits = [1 length(app.changed_signal)];
            app.M1EditField.Limits = [1 length(app.changed_signal)];
            cla(app.UIAxes,'reset');
            hold( app.UIAxes,'off');
            grid (app.UIAxes , 'on');
            selectedButton = app.PlotSettingButtonGroup.SelectedObject;
            x = app.changed_signal;
            f_x = fft(x);
            len = app.plot_len;
            switch selectedButton 
                case app.TimeDomainButton
                    title(app.UIAxes , 'Time Domain');
                    plot(app.UIAxes , x);
                case app.FrequencyDomainmagnitudeButton
                    title(app.UIAxes , 'Frequency Domain(magnitude)');
                    plot(app.UIAxes ,abs(f_x(1:len)));
                case app.FrequencyDomainphaseButton
                    title(app.UIAxes , 'Frequency Domain(phase)');
                    plot(app.UIAxes ,phase(f_x(1:len)));

            end
             value = app.Switch.Value;
            switch value 
                case 'Stop'
                    stop(app.player)
                case 'Play'
                                %sound(app.audioSignal , app.Fs);
                    app.player = audioplayer(app.changed_signal , app.Fs);
                    play(app.player);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value 
                case 'Stop'
                    stop(app.player)
                case 'Play'
                                %sound(app.audioSignal , app.Fs);
                    app.player = audioplayer(app.changed_signal , app.Fs);
                    play(app.player);
            end
        end

        % Selection changed function: PlotSettingButtonGroup
        function PlotSettingButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotSettingButtonGroup.SelectedObject;
            x = app.changed_signal;
            f_x = fft(x);
            len = app.plot_len;
            switch selectedButton 
                case app.TimeDomainButton
                    title(app.UIAxes , 'Time Domain');
                    plot(app.UIAxes , x);
                case app.FrequencyDomainmagnitudeButton
                    title(app.UIAxes , 'Frequency Domain(magnitude)');
                    plot(app.UIAxes ,abs(f_x(1:len)));
                case app.FrequencyDomainphaseButton
                    title(app.UIAxes , 'Frequency Domain(phase)');
                    plot(app.UIAxes ,phase(f_x(1:len)));
            end


        end

        % Button pushed function: MAButton
        function MAButtonPushed(app, event)
            m1 = app.M1EditField.Value ;
            m2 = app.M2EditField.Value;
%             app.movieEditField.Value = m1;
%             app.textEditField.Value = string(m2);
            temp = app.changed_signal(m1:m2);
            app.changed_signal = temp;
            app.plot_len = length(app.changed_signal);
            
            selectedButton = app.PlotSettingButtonGroup.SelectedObject;
            x = app.changed_signal;
            f_x = fft(x);
            len = app.plot_len;
            switch selectedButton 
                case app.TimeDomainButton
                    title(app.UIAxes , 'Time Domain');
                    plot(app.UIAxes , x);
                case app.FrequencyDomainmagnitudeButton
                    title(app.UIAxes , 'Frequency Domain(magnitude)');
                    plot(app.UIAxes ,abs(f_x(1:len)));
                case app.FrequencyDomainphaseButton
                    title(app.UIAxes , 'Frequency Domain(phase)');
                    plot(app.UIAxes ,phase(f_x(1:len)));
            end
                   value = app.Switch.Value;
            switch value 
                case 'Stop'
                    stop(app.player)
                case 'Play'
                                %sound(app.audioSignal , app.Fs);
                    app.player = audioplayer(app.changed_signal , app.Fs);
                    play(app.player);
            end    


        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            len_noise = length(app.changed_signal);
            noise = -0.01 + (0.01+0.01)*rand(1 , len_noise);
            app.changed_signal = app.changed_signal + noise ;
             selectedButton = app.PlotSettingButtonGroup.SelectedObject;
            x = app.changed_signal;
            f_x = fft(x);
            len = app.plot_len;
            switch selectedButton 
                case app.TimeDomainButton
                    title(app.UIAxes , 'Time Domain');
                    plot(app.UIAxes , x);
                case app.FrequencyDomainmagnitudeButton
                    title(app.UIAxes , 'Frequency Domain(magnitude)');
                    plot(app.UIAxes ,abs(f_x(1:len)));
                case app.FrequencyDomainphaseButton
                    title(app.UIAxes , 'Frequency Domain(phase)');
                    plot(app.UIAxes ,phase(f_x(1:len)));
            end
                        value = app.Switch.Value;
            switch value 
                case 'Stop'
                    stop(app.player)
                case 'Play'
                                %sound(app.audioSignal , app.Fs);
                    app.player = audioplayer(app.changed_signal , app.Fs);
                    play(app.player);
            end
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.OpenAudioFileButton = uibutton(app.Panel, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.AddNoiseButton = uibutton(app.Panel, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.MAButton = uibutton(app.Panel, 'push');
            app.MAButton.ButtonPushedFcn = createCallbackFcn(app, @MAButtonPushed, true);
            app.MAButton.Text = 'MA';
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'Stop', 'Play'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Stop';
            app.MASettingPanel = uipanel(app.UIFigure);
            app.MASettingPanel.Title = 'MA Setting';
            app.M2EditFieldLabel = uilabel(app.MASettingPanel);
            app.M2EditFieldLabel.Text = 'M2';
            app.M2EditField = uieditfield(app.MASettingPanel, 'numeric');
            app.M1EditFieldLabel = uilabel(app.MASettingPanel);
            app.M1EditFieldLabel.Text = 'M1';
            app.M1EditField = uieditfield(app.MASettingPanel, 'numeric');
            app.M1EditField.Value = 1;
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.PlotSettingButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotSettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotSettingButtonGroupSelectionChanged, true);
            app.PlotSettingButtonGroup.Title = 'Plot Setting';
            app.TimeDomainButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.TimeDomainButton.Value = true;
            app.FrequencyDomainmagnitudeButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.FrequencyDomainmagnitudeButton.Text = 'Frequency Domain(magnitude)';
            app.FrequencyDomainphaseButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.FrequencyDomainphaseButton.Text = 'Frequency Domain(phase)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end